classdef VISLA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LinearCombinationButton  matlab.ui.control.Button
        pWin                     matlab.ui.control.UIAxes
        InputVectorsPanel        matlab.ui.container.Panel
        scalV                    matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
        vLabel                   matlab.ui.control.Label
        wLabel                   matlab.ui.control.Label
        v1                       matlab.ui.control.NumericEditField
        v2                       matlab.ui.control.NumericEditField
        v3                       matlab.ui.control.NumericEditField
        scalW                    matlab.ui.control.NumericEditField
        w1                       matlab.ui.control.NumericEditField
        w2                       matlab.ui.control.NumericEditField
        w3                       matlab.ui.control.NumericEditField
        xLabel                   matlab.ui.control.Label
        yLabel                   matlab.ui.control.Label
        zLabel                   matlab.ui.control.Label
        TogglePanel              matlab.ui.container.Panel
        ThirdDimensionCheckBox   matlab.ui.control.CheckBox
        ScalarsCheckBox          matlab.ui.control.CheckBox
        AnimateCheckBox          matlab.ui.control.CheckBox
        OutcomePanel             matlab.ui.container.Panel
        OLabel                   matlab.ui.control.Label
        xLabel_2                 matlab.ui.control.Label
        yLabel_2                 matlab.ui.control.Label
        zLabel_2                 matlab.ui.control.Label
        outLabelX                matlab.ui.control.Label
        outLabelY                matlab.ui.control.Label
        outLabelZ                matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: LinearCombinationButton
        function lComb(app, event)
%             hold(app.pWin,'off') % So it resets the plot
            cla(app.pWin)
            
            scalSet = app.ScalarsCheckBox.Value;
            aniSet  = app.AnimateCheckBox.Value;
        % ########################
        % ### Plotting of V ######
        % ########################
            orig = [0 0];
            vecV = [app.v1.Value, app.v2.Value];
            if app.scalV.Value ~= 0 && scalSet
                vecV = vecV * app.scalV.Value;
            end
                        
%             quiver(app.pWin, orig(1),orig(2),vecV(1),vecV(2),0,'LineWidth',2,'Color','r')
            
        % ########################
        % ### Plotting of W ######
        % ########################
            hold(app.pWin,'on')
            vecW = [app.w1.Value, app.w2.Value];
            if app.scalW.Value ~= 0 && scalSet
                vecW = vecW * app.scalW.Value;
            end
            
%             quiver(app.pWin, orig(1),orig(2),vecW(1),vecW(2),0,'LineWidth',2,'Color','g')
            
        % ########################
        % # Plotting of Outcome ##
        % ########################
            vecO = vecV + vecW;
%             quiver(app.pWin, orig(1),orig(2),vecO(1),vecO(2),0,'LineWidth',2,'Color','b')
            
            % Showing the addition
            dpA = vecW;
%             quiver(app.pWin, orig(1),orig(2),vecV(1),vecV(2),0,'LineWidth',2,'Color','w','LineStyle',':')
%             quiver(app.pWin, vecV(1),vecV(2),dpA(1),dpA(2),0,'LineWidth',2,'Color','k','LineStyle',':')
            
            % Make sure that you are always one away from max and minimum
            minLim = min([orig vecV vecW vecO]) - 1;
            maxLim = max([orig vecV vecW vecO]) + 1;
            xlim(app.pWin,[minLim maxLim])
            ylim(app.pWin,[minLim maxLim])
            grid(app.pWin, 'on')
            
            
            % Animate or not
            aniLength = 25;
            if aniSet
                vecV_ani = [linspace(0,vecV(1),aniLength);linspace(0,vecV(2),aniLength)];
                vecW_ani = [linspace(0,vecW(1),aniLength);linspace(0,vecW(2),aniLength)];
                vecO_ani = [linspace(0,vecO(1),aniLength);linspace(0,vecO(2),aniLength)];
                for iAni = 1:aniLength
                    plot(app.pWin, [orig(1), vecV_ani(1,iAni)],[orig(2),vecV_ani(2,iAni)],'LineWidth',2,'Color','r')
                    plot(app.pWin, [orig(1), vecW_ani(1,iAni)],[orig(2),vecW_ani(2,iAni)],'LineWidth',2,'Color','g')
                    plot(app.pWin, [orig(1), vecO_ani(1,iAni)],[orig(2),vecO_ani(2,iAni)],'LineWidth',2,'Color','b')
%                     quiver(app.pWin, orig(1),orig(2),vecV_ani(1,iAni),vecV_ani(2,iAni),0,'LineWidth',2,'Color','r')
%                     quiver(app.pWin, orig(1),orig(2),vecW_ani(1,iAni),vecW_ani(2,iAni),0,'LineWidth',2,'Color','g')
%                     quiver(app.pWin, orig(1),orig(2),vecO_ani(1,iAni),vecO_ani(2,iAni),0,'LineWidth',2,'Color','b')
                    drawnow
                end
            end
                quiver(app.pWin, orig(1),orig(2),vecV(1),vecV(2),0,'LineWidth',2,'Color','r')
                quiver(app.pWin, orig(1),orig(2),vecW(1),vecW(2),0,'LineWidth',2,'Color','g')
                quiver(app.pWin, orig(1),orig(2),vecO(1),vecO(2),0,'LineWidth',2,'Color','b')
                
                quiver(app.pWin, orig(1),orig(2),vecV(1),vecV(2),0,'LineWidth',2,'Color','w','LineStyle',':')
                quiver(app.pWin, vecV(1),vecV(2),dpA(1),dpA(2),0,'LineWidth',2,'Color','k','LineStyle',':')

        % ########################
        % ## Write the outcome ###
        % ########################
        app.outLabelX.Text = num2str(vecO(1));
        app.outLabelY.Text = num2str(vecO(2));
        app.outLabelX.Visible = 'on';
        app.outLabelY.Visible = 'on';
        
%         app.outLabelZ.Text = vecO(3);
        end

        % Value changed function: ThirdDimensionCheckBox
        function add3D2(app, event)
            value = app.ThirdDimensionCheckBox.Value;
            if value == 1
                app.v3.Enable = 'on';
                app.w3.Enable = 'on';
                app.zLabel.FontColor = 'k';
                app.zLabel_2.FontColor = 'k';
            else
                app.v3.Enable = 'off';
                app.w3.Enable = 'off';
                app.zLabel.FontColor = [0.65,0.65,0.65];
                app.zLabel_2.FontColor = [0.65,0.65,0.65];
            end
        end

        % Value changed function: ScalarsCheckBox
        function toggleScalars(app, event)
            value = app.ScalarsCheckBox.Value;
            if value == 1
                app.scalV.Enable = 'on';
                app.scalW.Enable = 'on';
            else
                app.scalV.Enable = 'off';
                app.scalW.Enable = 'off';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LinearCombinationButton = uibutton(app.UIFigure, 'push');
            app.LinearCombinationButton.ButtonPushedFcn = createCallbackFcn(app, @lComb, true);
            app.LinearCombinationButton.Text = 'Linear Combination';
            app.pWin = uiaxes(app.UIFigure);
            title(app.pWin, '')
            xlabel(app.pWin, '')
            ylabel(app.pWin, '')
            app.InputVectorsPanel = uipanel(app.UIFigure);
            app.InputVectorsPanel.Title = 'Input Vectors';
            app.scalV = uieditfield(app.InputVectorsPanel, 'numeric');
            app.scalV.Limits = [-9 9];
            app.scalV.Enable = 'off';
            app.Label = uilabel(app.InputVectorsPanel);
            app.Label.Text = '+';
            app.vLabel = uilabel(app.InputVectorsPanel);
            app.vLabel.Text = 'v';
            app.wLabel = uilabel(app.InputVectorsPanel);
            app.wLabel.Text = 'w';
            app.v1 = uieditfield(app.InputVectorsPanel, 'numeric');
            app.v1.Limits = [-9 9];
            app.v2 = uieditfield(app.InputVectorsPanel, 'numeric');
            app.v2.Limits = [-9 9];
            app.v3 = uieditfield(app.InputVectorsPanel, 'numeric');
            app.v3.Limits = [-9 9];
            app.v3.Enable = 'off';
            app.scalW = uieditfield(app.InputVectorsPanel, 'numeric');
            app.scalW.Limits = [-9 9];
            app.scalW.Enable = 'off';
            app.w1 = uieditfield(app.InputVectorsPanel, 'numeric');
            app.w1.Limits = [-9 9];
            app.w2 = uieditfield(app.InputVectorsPanel, 'numeric');
            app.w2.Limits = [-9 9];
            app.w3 = uieditfield(app.InputVectorsPanel, 'numeric');
            app.w3.Limits = [-9 9];
            app.w3.Enable = 'off';
            app.xLabel = uilabel(app.InputVectorsPanel);
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.InputVectorsPanel);
            app.yLabel.Text = 'y';
            app.zLabel = uilabel(app.InputVectorsPanel);
            app.zLabel.Text = 'z';
            app.TogglePanel = uipanel(app.UIFigure);
            app.TogglePanel.Title = 'Toggle';
            app.ThirdDimensionCheckBox = uicheckbox(app.TogglePanel);
            app.ThirdDimensionCheckBox.ValueChangedFcn = createCallbackFcn(app, @add3D2, true);
            app.ThirdDimensionCheckBox.Text = 'Third Dimension';
            app.ScalarsCheckBox = uicheckbox(app.TogglePanel);
            app.ScalarsCheckBox.ValueChangedFcn = createCallbackFcn(app, @toggleScalars, true);
            app.ScalarsCheckBox.Text = 'Scalars';
            app.AnimateCheckBox = uicheckbox(app.TogglePanel);
            app.AnimateCheckBox.Text = 'Animate';
            app.OutcomePanel = uipanel(app.UIFigure);
            app.OutcomePanel.Title = 'Outcome';
            app.OLabel = uilabel(app.OutcomePanel);
            app.OLabel.Text = 'O';
            app.xLabel_2 = uilabel(app.OutcomePanel);
            app.xLabel_2.Text = 'x';
            app.yLabel_2 = uilabel(app.OutcomePanel);
            app.yLabel_2.Text = 'y';
            app.zLabel_2 = uilabel(app.OutcomePanel);
            app.zLabel_2.Text = 'z';
            app.outLabelX = uilabel(app.OutcomePanel);
            app.outLabelX.Visible = 'off';
            app.outLabelX.Text = 'x';
            app.outLabelY = uilabel(app.OutcomePanel);
            app.outLabelY.Visible = 'off';
            app.outLabelY.Text = 'y';
            app.outLabelZ = uilabel(app.OutcomePanel);
            app.outLabelZ.Visible = 'off';
            app.outLabelZ.Text = 'z';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '=';
        end
    end

    methods (Access = public)

        % Construct app
        function app = VISLA

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end